classdef live_telemetry < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        FuelPressurePanel              matlab.ui.container.Panel
        FuelPressureUnitLabel          matlab.ui.control.Label
        FuelPressureLabel              matlab.ui.control.Label
        OilPressurePanel               matlab.ui.container.Panel
        OilPressureUnitLabel           matlab.ui.control.Label
        OilPressureLabel               matlab.ui.control.Label
        CoolantFlowPanel               matlab.ui.container.Panel
        CoolantFlowUnitLabel           matlab.ui.control.Label
        CoolantFlowLabel               matlab.ui.control.Label
        LambdaPanel                    matlab.ui.container.Panel
        LambdaUnitLabel                matlab.ui.control.Label
        LambdaLabel                    matlab.ui.control.Label
        EngineCoolantTemperaturePanel  matlab.ui.container.Panel
        EngineCoolantTempUnit          matlab.ui.control.Label
        EngineCoolantTempLabel         matlab.ui.control.Label
        EngineOilTempPanel             matlab.ui.container.Panel
        EngineOilTempUnitLabel         matlab.ui.control.Label
        EngineOilTempLabel             matlab.ui.control.Label
        AccelerationPanel              matlab.ui.container.Panel
        AccelerationUnitLabel          matlab.ui.control.Label
        AccelerationLabel              matlab.ui.control.Label
        EngineRPMPanel                 matlab.ui.container.Panel
        EngineRPMUnitLabel             matlab.ui.control.Label
        EngineRPMLabel                 matlab.ui.control.Label
        EngineRPM                      matlab.ui.control.UIAxes
        EngineOilTemp                  matlab.ui.control.UIAxes
        EngineCoolantTemp              matlab.ui.control.UIAxes
        Lambda                         matlab.ui.control.UIAxes
        CoolantFlow                    matlab.ui.control.UIAxes
        OilPressure                    matlab.ui.control.UIAxes
        FuelPressure                   matlab.ui.control.UIAxes
        Acceleration                   matlab.ui.control.UIAxes
    end

    
properties (Access = private)
    % private variables for class goes here

    % live values for cards on right
    engineRPM = 0;
    engineOilTemp = 0;
    engineCoolantTemp = 0;
    lambda = 0;
    coolantFlow = 0;
    oilPressure = 0;
    fuelPressure = 0;
    acceleration = 0;

    % storing past values
    engineRPMHistory = [];
    engineOilTempHistory = [];
    engineCoolantTempHistory = [];
    lambdaHistory = [];
    coolantFlowHistory = [];
    oilPressureHistory = [];
    fuelPressureHistory = [];
    accelerationHistory = [];

    % timer object
    AppTimer
end
    
methods (Access = private)
    % private functions go here
    function apptimerCallback(app, src, event)
        
        % randomly updating the card values
        app.engineRPM = randi([0,8000]);
        app.engineOilTemp = randi([150,250]);
        app.engineCoolantTemp = randi([180,220]);
        app.lambda = 0.8 + 0.4 * rand();
        app.coolantFlow = randi([5,20]);
        app.oilPressure = randi([10,80]);
        app.fuelPressure = randi([30,60]);
        app.acceleration = 10 * randn();

        % updating charts and cards visulizations with values

        app.engineRPMHistory = [app.engineRPMHistory, app.engineRPM];
        app.EngineRPMLabel.Text = num2str(app.engineRPM, '%d');
        plot(app.EngineRPM, app.engineRPMHistory, 'r');

        drawnow limitrate;
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AppTimer = timer;

            % period is half a second
            app.AppTimer.period = 0.50;
            app.AppTimer.ExecutionMode = 'fixedRate';

            % setting app timer and callback function
            app.AppTimer.TimerFcn = @app.apptimerCallback;
            start(app.AppTimer)


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.AppTimer)
                if strcmp(app.AppTimer.Running, 'on')
                    stop(app.AppTimer);
                end
                delete(app.AppTimer);
            end

            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Acceleration = uiaxes(app.GridLayout);
            title(app.Acceleration, 'Acceleration')
            xlabel(app.Acceleration, 'Time')
            ylabel(app.Acceleration, 'Acceleration (m/s²)')
            zlabel(app.Acceleration, 'Z')
            app.FuelPressure = uiaxes(app.GridLayout);
            title(app.FuelPressure, 'Fuel Pressure')
            xlabel(app.FuelPressure, 'Time')
            ylabel(app.FuelPressure, 'Fuel Pressure (PSI)')
            zlabel(app.FuelPressure, 'Z')
            app.OilPressure = uiaxes(app.GridLayout);
            title(app.OilPressure, 'Oil Pressure')
            xlabel(app.OilPressure, 'Time')
            ylabel(app.OilPressure, 'Oil Pressure (PSI)')
            zlabel(app.OilPressure, 'Z')
            app.CoolantFlow = uiaxes(app.GridLayout);
            title(app.CoolantFlow, 'Coolant Flow')
            xlabel(app.CoolantFlow, 'Time')
            ylabel(app.CoolantFlow, 'Coolant Flow (L/min)')
            zlabel(app.CoolantFlow, 'Z')
            app.Lambda = uiaxes(app.GridLayout);
            title(app.Lambda, 'Lambda')
            xlabel(app.Lambda, 'Time')
            ylabel(app.Lambda, 'Lambda')
            zlabel(app.Lambda, 'Z')
            app.EngineCoolantTemp = uiaxes(app.GridLayout);
            title(app.EngineCoolantTemp, 'Engine Coolant Temperature')
            xlabel(app.EngineCoolantTemp, 'Time')
            ylabel(app.EngineCoolantTemp, 'Temperature (°F)')
            zlabel(app.EngineCoolantTemp, 'Z')
            app.EngineOilTemp = uiaxes(app.GridLayout);
            title(app.EngineOilTemp, 'Engine Oil Temperature')
            xlabel(app.EngineOilTemp, 'Time')
            ylabel(app.EngineOilTemp, 'Temperature (°F)')
            zlabel(app.EngineOilTemp, 'Z')
            app.EngineRPM = uiaxes(app.GridLayout);
            title(app.EngineRPM, 'Engine RPM')
            xlabel(app.EngineRPM, 'Time')
            ylabel(app.EngineRPM, 'RPM')
            zlabel(app.EngineRPM, 'Z')
            app.EngineRPMPanel = uipanel(app.GridLayout);
            app.EngineRPMPanel.Title = 'Engine RPM';
            app.EngineRPMLabel = uilabel(app.EngineRPMPanel);
            app.EngineRPMLabel.Text = '0';
            app.EngineRPMUnitLabel = uilabel(app.EngineRPMPanel);
            app.EngineRPMUnitLabel.Text = 'RPM';
            app.AccelerationPanel = uipanel(app.GridLayout);
            app.AccelerationPanel.Title = 'Acceleration';
            app.AccelerationLabel = uilabel(app.AccelerationPanel);
            app.AccelerationLabel.Text = '0';
            app.AccelerationUnitLabel = uilabel(app.AccelerationPanel);
            app.AccelerationUnitLabel.Text = 'm/s²';
            app.EngineOilTempPanel = uipanel(app.GridLayout);
            app.EngineOilTempPanel.Title = 'Engine Oil Temp';
            app.EngineOilTempLabel = uilabel(app.EngineOilTempPanel);
            app.EngineOilTempLabel.Text = '0';
            app.EngineOilTempUnitLabel = uilabel(app.EngineOilTempPanel);
            app.EngineOilTempUnitLabel.Text = '(°F)';
            app.EngineCoolantTemperaturePanel = uipanel(app.GridLayout);
            app.EngineCoolantTemperaturePanel.Title = 'Engine Coolant Temperature';
            app.EngineCoolantTempLabel = uilabel(app.EngineCoolantTemperaturePanel);
            app.EngineCoolantTempLabel.Text = '0';
            app.EngineCoolantTempUnit = uilabel(app.EngineCoolantTemperaturePanel);
            app.EngineCoolantTempUnit.Text = '(°F)';
            app.LambdaPanel = uipanel(app.GridLayout);
            app.LambdaPanel.Title = 'Lambda';
            app.LambdaLabel = uilabel(app.LambdaPanel);
            app.LambdaLabel.Text = '0';
            app.LambdaUnitLabel = uilabel(app.LambdaPanel);
            app.LambdaUnitLabel.Text = '';
            app.CoolantFlowPanel = uipanel(app.GridLayout);
            app.CoolantFlowPanel.Title = 'CoolantFlow';
            app.CoolantFlowLabel = uilabel(app.CoolantFlowPanel);
            app.CoolantFlowLabel.Text = '0';
            app.CoolantFlowUnitLabel = uilabel(app.CoolantFlowPanel);
            app.CoolantFlowUnitLabel.Text = 'L/min';
            app.OilPressurePanel = uipanel(app.GridLayout);
            app.OilPressurePanel.Title = 'Oil Pressure';
            app.OilPressureLabel = uilabel(app.OilPressurePanel);
            app.OilPressureLabel.Text = '0';
            app.OilPressureUnitLabel = uilabel(app.OilPressurePanel);
            app.OilPressureUnitLabel.Text = 'PSI';
            app.FuelPressurePanel = uipanel(app.GridLayout);
            app.FuelPressurePanel.Title = 'Engine RPM';
            app.FuelPressureLabel = uilabel(app.FuelPressurePanel);
            app.FuelPressureLabel.Text = '0';
            app.FuelPressureUnitLabel = uilabel(app.FuelPressurePanel);
            app.FuelPressureUnitLabel.Text = 'PSI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = live_telemetry

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end