classdef ui_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TableHeaderPanel            matlab.ui.container.Panel
        TableHeaderGridLayout       matlab.ui.container.GridLayout
        AccelerationHeader          matlab.ui.control.Label
        SpeedHeader                 matlab.ui.control.Label
        TimeHeader                  matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        AccelerationLiveValuePanel  matlab.ui.container.Panel
        AccelerationLiveValueGridLayout  matlab.ui.container.GridLayout
        AccelerationUnitsLabel      matlab.ui.control.Label
        AccelerationLiveValueLabel  matlab.ui.control.Label
        SpeedLiveValuePanel         matlab.ui.container.Panel
        SpeedLiveValueGridLayout    matlab.ui.container.GridLayout
        SpeedUnitsLabel             matlab.ui.control.Label
        SpeedLiveValueLabel         matlab.ui.control.Label
        InformationPanel            matlab.ui.container.Panel
        InformationPanelGridLayout  matlab.ui.container.GridLayout
        UpdateInformationButton     matlab.ui.control.Button
        StatusPanel                 matlab.ui.container.Panel
        SessionInfoGridLayoud_2     matlab.ui.container.GridLayout
        BestLapValueLabel           matlab.ui.control.Label
        DistanceTraveledLabel       matlab.ui.control.Label
        FuelPercentageLabel         matlab.ui.control.Label
        TireLifeLapsLabel           matlab.ui.control.Label
        TireHealthLabel             matlab.ui.control.Label
        BestLapLabel                matlab.ui.control.Label
        DistanceLabel               matlab.ui.control.Label
        FuelLabel                   matlab.ui.control.Label
        TireLifeLabel               matlab.ui.control.Label
        TireLabel                   matlab.ui.control.Label
        SessionInfoPanel            matlab.ui.container.Panel
        SessionInfoGridLayoud       matlab.ui.container.GridLayout
        PositionValueLabel          matlab.ui.control.Label
        GapTimeLabel                matlab.ui.control.Label
        LapTimeValueLabel           matlab.ui.control.Label
        LapCountLabel               matlab.ui.control.Label
        SessionTypeLabel            matlab.ui.control.Label
        PositionLabel               matlab.ui.control.Label
        GapLabel                    matlab.ui.control.Label
        LapTimeLabel                matlab.ui.control.Label
        LapLabel                    matlab.ui.control.Label
        SessionLabel                matlab.ui.control.Label
        DriverInfoPanel             matlab.ui.container.Panel
        DriverInfoGridLayout        matlab.ui.container.GridLayout
        DriverNumberLabel           matlab.ui.control.Label
        TeamLabel                   matlab.ui.control.Label
        DriverLabel                 matlab.ui.control.Label
        ToolbarPanel                matlab.ui.container.Panel
        ToolbarPanelGridLayout      matlab.ui.container.GridLayout
        RunButton                   matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        ClearButton                 matlab.ui.control.Button
        LoadButton                  matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        SpeedGraph                  matlab.ui.control.UIAxes
        AccelerationGraph           matlab.ui.control.UIAxes
    end

    
    % User defined variables
    properties (Access = private)
        TCPServer
        Serial

        AccelerationData = []
        SpeedData = []
        TimeData = []
        StartTime datetime
        busyMode = "idle"

        CurrentAcceleration = 0
        CurrentSpeed = 0
        CurrentTime = 0
        deltaTime = 0

        lapNum = 0
        currLapTime = 0;
        lastLapTime = 0;
        bestLapTime = 0;

        Timer

        DriverIDs double = []
    end
    
    methods (Access = private)
        function time = secToLapFormat(~, seconds)
            minutes = floor(seconds/60);
            remainingSeconds = seconds - minutes * 60;
            time = sprintf('%d:%06.3f', minutes, remainingSeconds);
        end

        function randomData(app)
            app.CurrentAcceleration = app.CurrentAcceleration * 0.92 + (rand() - 0.5) * 2.0;
            app.CurrentAcceleration = max(-20, min(20, app.CurrentAcceleration));
            newTime = toc;
            if isempty(app.SpeedData)
                newSpeed = 0;
            else
                newSpeed = app.SpeedData(end) + app.CurrentAcceleration * 0.05;
            end
            app.CurrentTime = newTime;
            app.CurrentSpeed = newSpeed;
        end


        % replots the speed and acceleration
        function replot(app)
            try
                plot(app.AccelerationGraph, app.TimeData, app.AccelerationData);
                plot(app.SpeedGraph, app.TimeData, app.SpeedData);
                drawnow;
            catch
            end
        end

        % updates the info under the session info group
        function updateSessionInfo(app, distance)
            lap_calc = floor(distance / 2);
            app.currLapTime = toc;

            if(app.lapNum < lap_calc)
                app.lapNum = lap_calc;
                timeDifference = app.currLapTime - app.lastLapTime;
                gap = timeDifference - 67; % for gap

                app.LapCountLabel.Text = sprintf("%d/67", app.lapNum);
                app.LapTimeValueLabel.Text = "0:00.000";
                app.GapTimeLabel.Text = secToLapFormat(app, gap);

                if(app.bestLapTime == 0 || timeDifference < app.bestLapTime)
                    app.bestLapTime = timeDifference;
                end
                app.lastLapTime = app.currLapTime;
                app.currLapTime = 0;
            else
                lap_time = app.currLapTime - app.lastLapTime;
                lap_time_str = secToLapFormat(app, lap_time);
                app.LapTimeValueLabel.Text = lap_time_str;
            end

        end

        function updateStatusInfo(app, distance)
            laps_left = 67 - app.lapNum;
            app.TireLifeLapsLabel.Text = sprintf("%d Laps", laps_left);

            if(app.lapNum < 32)
                app.TireHealthLabel.Text = "Good";
                app.TireLifeLapsLabel.FontColor = [0.00 0.80 0.53];
                app.TireHealthLabel.FontColor = [0.00 0.80 0.53];
            elseif(app.lapNum > 50)
                app.TireHealthLabel.Text = "Bad";
                app.TireLifeLapsLabel.FontColor = [1.0, 0.2, 0.2];
                app.TireHealthLabel.FontColor = [1.0, 0.2, 0.2];
            else
                app.TireHealthLabel.Text = "Medium";
                app.TireLifeLapsLabel.FontColor = [1.00 0.80 0.00];
                app.TireHealthLabel.FontColor = [1.00 0.80 0.00];
            end

            fuel = floor(100 * app.lapNum / 67);
            app.FuelPercentageLabel.Text = sprintf("%d%%", fuel);
            app.DistanceTraveledLabel.Text = sprintf("%.2f mi", distance);
            app.BestLapValueLabel.Text = secToLapFormat(app, app.bestLapTime);
        end

        function updateTable(app)
            try
                newRow = table( ...
                    app.CurrentTime, app.CurrentSpeed, app.CurrentAcceleration, ...
                    'VariableNames', {'time','speed','acceleration'});
                if isempty(app.UITable.Data)
                    app.UITable.Data = newRow;
                else                 
                    app.UITable.Data = [newRow; app.UITable.Data];
                end
            catch
            end
        end

        function updateInformation(app)
            % Have points, so use trapezoidal rule to get distance

            if length(app.TimeData) < 2
                return;
            end

            % Remove duplicate timestamps before integrating
            [uniqueTime, idx] = unique(app.TimeData);
            if length(uniqueTime) < 2
                return;
            end

            distance = trapz(app.TimeData, app.SpeedData(idx)); % use idx

            updateSessionInfo(app, distance); % session
            updateStatusInfo(app, distance); % status
        end
        
        function updatePlot(app)
            % use random data if no tcp
            % randomData(app);

            newAcceleration = app.CurrentAcceleration;

            app.TimeData = [app.TimeData, app.CurrentTime];
            app.AccelerationData = [app.AccelerationData, newAcceleration];
            app.SpeedData = [app.SpeedData, app.CurrentSpeed];

            replot(app);
            updateTable(app);

            app.SpeedLiveValueLabel.Text = sprintf('%.1f', app.CurrentSpeed);
            app.AccelerationLiveValueLabel.Text = sprintf('%.2f', app.CurrentAcceleration);
            updateInformation(app);
        end

        function dlg = showBusyDialog(app, titleText, messageText)

            dlg = uiprogressdlg(app.UIFigure, ...
                'Title', titleText, ...
                'Message', messageText, ...
                'Indeterminate', 'on', ...
                'Cancelable', 'off');
        end

        function closeBusyDialog(~, dlg)
            if ~isempty(dlg) && isvalid(dlg)
                close(dlg);
            end
        end
        
        function stopRun(app)
            if isempty(app.Timer) || ~isa(app.Timer,'timer')
                return;
            end
            
            try
                if strcmp(app.Timer.Running, 'on')
                    stop(app.Timer);
                end
            catch ME
                try
                    warning("Timer stop/delete failed: %s", string(ME.message));
                catch
                    warning("Timer stop/delete failed (unknown error)");
                end
            end
        end
        
        function clearRun(app)
            app.AccelerationData = [];
            app.SpeedData = [];
            app.TimeData = [];
            app.StartTime = NaT;
            app.CurrentAcceleration = 0;
            app.lapNum = 0;
            app.currLapTime = 0;
            app.lastLapTime = 0;
            app.bestLapTime = 0;
            cla(app.AccelerationGraph);
            cla(app.SpeedGraph);

            %replot(app);
            app.AccelerationLiveValueLabel.Text = '0';
            app.SpeedLiveValueLabel.Text = '0';
            app.UITable.Data = table( ...
                zeros(20,1), zeros(20,1), zeros(20,1), ...
                'VariableNames', {'time','speed','acceleration'} ...
            );            
        end
        
        function allowed = canRun(app, action)
            if ~isempty(app.Timer) && isvalid(app.Timer) && strcmp(app.Timer.Running, "on")
                currentMode = "running";
            else
                currentMode = app.busyMode;
            end
            switch currentMode
                case "idle"
                    allowed = true;
                case {"loading","saving","updating"}
                    allowed = false;
                case "running"
                    allowed = any(action == ["stop","clear"]);
                otherwise
                    allowed = false;
            end
        end
        
        function updateUI(app)
            app.RunButton.Enable = app.canRun("run");
            app.StopButton.Enable = app.canRun("stop");
            app.ClearButton.Enable = app.canRun("clear");
            app.LoadButton.Enable = app.canRun("load");
            app.SaveButton.Enable  = app.canRun("save");
            app.UpdateInformationButton.Enable = app.canRun("update");
        end

        function TCPCallBack(app, src, ~)
            % this needs to be configured for when we know what data we
            % getting. We're getting pulses so the main formulas are:
            % Speed = speed = rps * circumference
            % Acceleration = vt - v0 / t
            if src.BytesAvailable > 0
                data = read(src, src.BytesAvailable, "string");

                rpm = sscanf(data, 'RPM=%f');

                % circumference in miles
                circumference = 0.00003318;
                rph = rpm * 60;
                fprintf("Type: %s -- RPM: %d\n", class(rpm), rpm);
            
                t = toc - app.CurrentTime;
            
                speed = rph * circumference;
                acceleration = (speed - app.CurrentSpeed) / t;

                app.CurrentAcceleration = acceleration;
                app.CurrentSpeed = speed;
                app.CurrentTime = toc;
                updatePlot(app);
             end
        end

        function readData(app, src) % Parse RPM from ESP32
            % speed = rps * circumference
            % Acceleration = vt - v0 / t

            % Input is of form: RPM=1234\r\n. Remove carriage return, \r!
            line = strtrim(readline(src)); %strtrim removes /r and /n 
            rpm = sscanf(line, 'RPM=%f'); % silently fails from /r and /n

            if isempty(rpm) % if a line is empty, it will be unparseable
                fprintf("Skipping unparseable line: '%s'\n", line);
                return; % bail-out early to avoid corrupting state
            end

            circumference = 0.0001238952; % circumference in miles
            rps = rpm / 60; % rps from rpm
            
            newTime = toc;
            t = (newTime - app.CurrentTime) * 3600; % hours from seconds

            if t <= 0
                return; % avoid dividing by 0 on first call
            end

            fprintf("Type: %s -- RPM: %d\n", class(rpm), rpm);
            
            speed = rps * circumference * 3600; % mph = rps * miles * 3600s/hr
            acceleration = (speed - app.CurrentSpeed) / t;

            app.CurrentAcceleration = acceleration;
            app.CurrentSpeed = speed;
            app.CurrentTime = newTime;
            updatePlot(app);
        end

        
        function ok = startBusy(app, mode)
            if ~app.canRun(mode)
                ok = false;
                return;
            end

            app.busyMode = mode;
            app.updateUI();
            ok = true;           
        end
        
        function endBusy(app)
            app.busyMode = "idle";
            app.updateUI();
        end
        
        function handleError(app, ME, titleText, dlg, conn)
            try
                if ~isempty(dlg) && isvalid (dlg)
                    close(dlg);
                end
            catch
            end

            try 
                if ~isempty(conn) 
                    try
                        if isopen(conn)
                            exec(conn,"ROLLBACK");
                        end
                    catch
                    end
                end
            catch
            end

            warning("Error: %s\n%s", ME.message, getReport(ME));
            uialert(app.UIFigure, ME.message, titleText);            
        end
        
        function safeUpdate(app)

            try
                app.updatePlot();
            catch ME
                app.stopRun();
                app.endBusy();
                app.handleError(ME, "Runtime Error", [], []);
            end
        end
        
        function ok = isTimerOkay(app)
            ok = ~isempty(app.Timer) && isa(app.Timer,'timer') && isvalid(app.Timer);
            if ~ok
                return;
            end
            
            try
                state = app.Timer.Running;
                ok = ischar(state) || isstring(state);
            catch
                ok = false;
            end            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Getting stuff I couldn't change in the component browser
            app.SpeedGraph.Title.Color = [0.67 0.8 0.93];
            app.AccelerationGraph.Title.Color = [0.67 0.8 0.93];
            app.UITable.ColumnName = {'Time','Speed','Acceleration'};

            % Initializes table with zeros to prevent flashing as data
            % starts populating
            app.UITable.Data = table( ...
                zeros(20,1), zeros(20,1), zeros(20,1), ...
                'VariableNames', {'time','speed','acceleration'} ...
            );
                        
            % Setting for timer
            app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 5.05, ...
                              'TimerFcn', @(~,~) []);

            % app.TCPServer = tcpserver("0.0.0.0", 6767);
            % configureCallback(app.TCPServer, "byte", 1, @app.TCPCallBack);

            app.Serial = serialport("/dev/cu.usbserial-0001", 115200);
            configureTerminator(app.Serial, "LF"); % TEST: remove carriage return. Will it cause issue if combined with strtrim?
            configureCallback(app.Serial, "terminator", @(src,evt) app.readData(src));

            tic; % Start the clock, so toc is always valid.
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if ~isempty(app.Timer) && isa(app.Timer, 'timer')
                try
                    if strcmp(app.Timer.Running, 'on')
                        stop(app.Timer);
                    end
                catch
                end
            end
            delete(app.TCPServer);

            delete(app);
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if ~app.startBusy("running")
                return;
            end
            
            if ~app.isTimerOkay()
                warning("Timer broke, recreating...");
                app.Timer = timer('ExecutionMode', 'fixedRate', ...
                              'Period', 0.05, ...
                              'TimerFcn', @(~,~) safeUpdate(app));
            end
            
            if strcmp(app.Timer.Running, 'off')
                app.StartTime = datetime('now');
                app.AccelerationData = [];
                app.SpeedData = [];
                app.TimeData = [];
                app.CurrentAcceleration = 0;
                app.CurrentSpeed = 0;
                app.CurrentTime = 0;

                tic
                start(app.Timer)
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stopRun(app);
            app.endBusy();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            stopRun(app);
            clearRun(app);
            app.endBusy();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            if ~app.startBusy("loading")
                return;
            end
            cleanup = onCleanup(@() app.endBusy());

            conn = [];
            dlg = [];

            try
                dlg = app.showBusyDialog("Loading...","Connecting...");

                % Path to JDBC driver inside project
                jdbcPath = fullfile(pwd, 'database', 'JDBC', 'postgresql-42.7.10.jar');
                if ~any(strcmp(javaclasspath('-dynamic'), jdbcPath))
                    javaaddpath(jdbcPath);
                end
                
                conn = database('postgres', 'postgres', 'wacky', ...
                    'Vendor', 'PostgreSQL', ...
                    'Server', '127.0.0.1', ...
                    'PortNumber', 5432);
                cleanupConn = onCleanup(@() close(conn));

                if ~isopen(conn)
                    error("Database connection failed");
                end

                dlg.Message = "Reading database...";
                
                sql = "SELECT s.session_id, s.session_date, d.name, d.driver_number, d.team " + ...
                "FROM session s " + ...
                "JOIN driver d ON s.driver_id = d.driver_id " + ...
                "ORDER BY s.session_date DESC, s.session_id DESC";
                
                sessions = fetch(conn, sql);
    
                items = strings(height(sessions),1);
    
                for i = 1:height(sessions)
                    items(i) = sprintf("Session %d | %s | Driver %s (#%d)", ...
                        sessions.session_id(i), ...
                        string(sessions.session_date(i)), ...
                        string(sessions.name(i)), ...
                        sessions.driver_number(i));
                end
                
                [idx, ok] = listdlg( ...
                    'PromptString', 'Select a session:', ...
                    'SelectionMode', 'single', ...
                    'ListString', items,...
                    'ListSize', [900 300]);
                
                if ~ok
                    app.closeBusyDialog(dlg);
                    return;
                end
    
                session_id = sessions.session_id(idx);
    
                stopRun(app);
                clearRun(app);
    
                sql = sprintf( ...
                    "SELECT time, speed, acceleration FROM telemetry WHERE session_id = %d ORDER BY time ASC", ...
                    session_id);
                
                T = fetch(conn, sql);

                if isempty(T)
                    uialert(app.UIFigure,"No telemetry found for session","Load Error");
                    closeBusyDialog(app, dlg);
                    return;
                end
    
                t0 = T.time(1);
                T.time = datetime(T.time, 'TimeZone', 'UTC');
                T.time = seconds(T.time - t0);
                
    
                T.speed = double(T.speed);
                T.acceleration = double(T.acceleration);
                
                app.UITable.Data = T;
                
                app.TimeData = T.time';
                app.SpeedData = double(T.speed)';
                app.AccelerationData = double(T.acceleration)';
    
                app.CurrentTime = app.TimeData(end);
                app.CurrentSpeed = app.SpeedData(end);
                app.CurrentAcceleration = app.AccelerationData(end);
    
                replot(app);
    
                app.SpeedLiveValueLabel.Text = sprintf('%.1f', app.CurrentSpeed);
                app.AccelerationLiveValueLabel.Text = sprintf('%.2f', app.CurrentAcceleration);
    
                app.DriverLabel.Text = sessions.name(idx);
                app.DriverNumberLabel.Text = num2str(sessions.driver_number(idx));
                app.TeamLabel.Text = sessions.team(idx);

                dlg.Message = "Finalizing load...";
                pause(.2);

                closeBusyDialog(app, dlg);
                uialert(app.UIFigure, "Run loaded successfully", "Success");

            catch ME
                app.handleError(ME, "Load Error", dlg, conn);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if ~app.startBusy("saving")
                return;
            end
            cleanup = onCleanup(@() app.endBusy());

            conn = [];
            dlg = [];

            try
                dlg = app.showBusyDialog("Loading...","Connecting...");
            
                % Path to JDBC driver inside project
                jdbcPath = fullfile(pwd, 'database', 'JDBC', 'postgresql-42.7.10.jar');
                
                % Add to MATLAB dynamic classpath if not already added
                if ~any(strcmp(javaclasspath('-dynamic'), jdbcPath))
                    javaaddpath(jdbcPath);
                end
                
                conn = database('postgres', 'postgres', 'wacky', ...
                    'Vendor', 'PostgreSQL', ...
                    'Server', '127.0.0.1', ...
                    'PortNumber', 5432);
                cleanupConn = onCleanup(@() close(conn));

                if ~isopen(conn)
                    error("Database connection failed");
                end
                exec(conn, "BEGIN");
                
                if isnat(app.StartTime)
                    error("Run has not been started.")
                end
                
                stopRun(app);

                validRows = ~isnan(app.TimeData) & ~isnan(app.SpeedData) & ~isnan(app.AccelerationData);
                                
                if ~any(validRows)
                    error("Save:NoData","No valid data to save");
                end

                timeData = app.TimeData(validRows);
                speedData = app.SpeedData(validRows);
                accelData = app.AccelerationData(validRows);
    
                name = app.DriverLabel.Text;
                name = strrep(name, "'", "''");
                team = app.TeamLabel.Text;
                team = strrep(team, "'", "''");
                driverNumber = str2double(app.DriverNumberLabel.Text);
    
                sql = sprintf( ...
                    "SELECT driver_id FROM driver WHERE name = '%s' AND driver_number = %d", ...
                    name, driverNumber);
                data = fetch(conn, sql);
    
                if ~isempty(data)
                    driver_id = data.driver_id(1);
                else
                    sql = sprintf( ...
                        "INSERT INTO Driver (name, team, driver_number) VALUES ('%s','%s',%d) RETURNING driver_id", ...
                        name, team, driverNumber);
                    result = fetch(conn, sql);
                    driver_id = result.driver_id(1);
                end
    
               
                todayStr = string(datetime("today"), 'yyyy-MM-dd');
                sql = sprintf( ...
                    "INSERT INTO Session (driver_id, session_date) VALUES (%d, '%s') RETURNING session_id", ...
                    driver_id, todayStr);
                result = fetch(conn, sql);
                session_id = result.session_id(1);
    
                startTime = datetime('now','TimeZone', 'UTC');
                absTime = startTime + seconds(timeData);

                T = table( ...
                    absTime', ...
                    speedData', ...
                    accelData', ...
                    repmat(session_id, length(timeData), 1), ...
                    repmat(driver_id, length(timeData), 1), ...
                    'VariableNames', {'time','speed','acceleration','session_id','driver_id'} ...
                );

                sqlwrite(conn, "Telemetry", T);

                dlg.Message = "Writing telemetry...";
                pause(.2)

                exec(conn, "COMMIT");
                uialert(app.UIFigure, "Run saved successfully", "SUCCESS");
                closeBusyDialog(app,dlg);
                
                clearRun(app);

            catch ME
                app.handleError(ME, "Save Error", dlg, conn);
            end
        end

        % Button pushed function: UpdateInformationButton
        function UpdateInformationButtonPushed(app, event)
            if ~app.startBusy("updating")
                return;
            end
            cleanup = onCleanup(@() app.endBusy());
            
            dlg = uifigure('Name','Edit Info', 'Position',[600 400 360 440], ...
                'Color', [0.1294 0.1294 0.1294]);
            
            uilabel(dlg,'Text','Driver','Position',[50 400 250 20], ...
                'FontColor',[0.85 0.85 0.85]);
            
            driverDropdown = uidropdown(dlg, ...
                'Position',[50 370 250 30], ...
                'Items', {'<New Driver>'});
            
            nameField = uieditfield(dlg,'text', ...
                'Position',[50 320 250 30], ...
                'Placeholder','Driver Name', ...
                'BackgroundColor',[0.1294 0.1294 0.1294], ...
                'FontColor',[0.85 0.85 0.85]);
            
            teamField = uieditfield(dlg,'text', ...
                'Position',[50 280 250 30], ...
                'Placeholder','Team', ...
                'BackgroundColor',[0.1294 0.1294 0.1294], ...
                'FontColor',[0.85 0.85 0.85]);
            
            numField = uieditfield(dlg,'text', ...
                'Position',[50 240 250 30], ...
                'Placeholder','Driver Number', ...
                'BackgroundColor',[0.1294 0.1294 0.1294], ...
                'FontColor',[0.85 0.85 0.85]);
            
            uilabel(dlg,'Text','Session','Position',[50 200 250 20], ...
                'FontColor',[0.85 0.85 0.85]);
            
            sessionField = uidropdown(dlg, ...
                'Position',[50 170 250 30], ...
                'Items', {'Testing','Training','Practice','Qualifying','Race'}, ...
                'Value', app.SessionTypeLabel.Text);
            
            applyBtn = uibutton(dlg,'push', ...
                'Text','Apply', ...
                'Position',[105 60 150 40], ...
                'Enable','off');
            
            conn = [];
            driverTable = [];
            
            try
                conn = database('postgres','postgres','wacky', ...
                    'Vendor','PostgreSQL','Server','127.0.0.1','PortNumber',5432);
            
                if ~isopen(conn)
                    error("DB connection failed");
                end
            
                driverTable = fetch(conn, ...
                    "SELECT driver_id, name, team, driver_number FROM driver ORDER BY name");
            
                close(conn);
            catch
                driverTable = table([],[],[],[], ...
                    'VariableNames',{'driver_id','name','team','driver_number'});
            end
            
            items = strings(height(driverTable)+1,1);
            items(1) = "<New Driver>";
            
            for i = 1:height(driverTable)
                items(i+1) = driverTable.name(i) + " (#" + string(driverTable.driver_number(i)) + ")";
            end
            
            driverDropdown.Items = items;
            
            function setFieldsEnabled(tf)
                nameField.Enable = tf;
                teamField.Enable = tf;
                numField.Enable = tf;
            end
            
            setFieldsEnabled(true);
            
            function validate()
                v = str2double(numField.Value);
            
                if driverDropdown.ValueIndex == 1
                    ok = ~isempty(nameField.Value) && ...
                         ~isempty(teamField.Value) && ...
                         ~isnan(v) && v > 0 && mod(v,1)==0;
            
                    applyBtn.Enable = ternary(ok,'on','off');
            
                    if isnan(v) || v <= 0
                        numField.BackgroundColor = [0.25 0.08 0.08];
                    else
                        numField.BackgroundColor = [0.1294 0.1294 0.1294];
                    end
                else
                    applyBtn.Enable = "on";
                end
            end
            
            function out = ternary(cond,a,b)
                if cond, out = a; else, out = b; end
            end
            
            driverDropdown.ValueChangedFcn = @(~,~) loadDriver();
            
            function loadDriver()

                val = driverDropdown.Value;
                
                if strcmp(val,"<New Driver>")
                    setFieldsEnabled(true);
                    nameField.Value = "";
                    teamField.Value = "";
                    numField.Value = "";
                    validate();
                    return;
                end
                
                matchIdx = find(strcmp(items, val), 1);

                if isempty(matchIdx) || matchIdx == 1
                    setFieldsEnabled(true);
                    return;
                end
                
                d = driverTable(matchIdx-1,:);
                                
                nameField.Value = string(d.name);
                teamField.Value = string(d.team);
                numField.Value = string(d.driver_number);
                
                if ismissing(nameField.Value), nameField.Value = ""; end
                if ismissing(teamField.Value), teamField.Value = ""; end
                if ismissing(numField.Value), numField.Value = ""; end
                
                setFieldsEnabled(false);
                applyBtn.Enable = "on";
                
            end
            
            numField.ValueChangedFcn = @(~,~) validate();
            nameField.ValueChangedFcn = @(~,~) validate();
            teamField.ValueChangedFcn = @(~,~) validate();
            
            applyBtn.ButtonPushedFcn = @(~,~) applyChanges();
            
            function applyChanges()                          
                name = strtrim(nameField.Value);
                team = strtrim(teamField.Value);
                num = str2double(numField.Value);

                if isnan(num)
                    uialert(dlg,'Invalid driver number','Error');
                    return;
                end

                if driverDropdown.ValueIndex == 1 && (isnan(num) || isempty(name) || isempty(team))
                    uialert(dlg,'Invalid input','Error');
                    return;
                end
            
                if driverDropdown.ValueIndex == 1
            
                    conn = database('postgres','postgres','wacky', ...
                        'Vendor','PostgreSQL','Server','127.0.0.1','PortNumber',5432);
            
                    if ~isopen(conn)
                        uialert(dlg,'DB connection failed','Error');
                        return;
                    end
            
                    exists = fetch(conn, sprintf( ...
                        "SELECT COUNT(*) AS c FROM driver WHERE driver_number=%d", num));
                    
                    if exists.c(1) > 0
                        uialert(dlg,'Driver number already exists','Error');
                        close(conn);
                        return;
                    end
            
                    exec(conn, sprintf( ...
                        "INSERT INTO driver (name, team, driver_number) VALUES ('%s','%s',%d)", ...
                        strrep(name,"'","''"), strrep(team,"'","''"), num));
            
                    close(conn);
            
                else
                    d = driverTable(driverDropdown.ValueIndex-1,:);
            
                    conn = database('postgres','postgres','wacky', ...
                        'Vendor','PostgreSQL','Server','127.0.0.1','PortNumber',5432);
            
                    if isopen(conn)
                        exec(conn, sprintf( ...
                            "UPDATE driver SET name='%s', team='%s' WHERE driver_id=%d", ...
                            strrep(name,"'","''"), strrep(team,"'","''"), d.driver_id));
            
                        close(conn);
                    end
                end
            
                app.DriverLabel.Text = name;
                app.TeamLabel.Text = team;
                app.DriverNumberLabel.Text = num2str(num);
                app.SessionTypeLabel.Text = sessionField.Value;
            
                close(dlg);
            end
            
            validate();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.AccelerationGraph = uiaxes(app.GridLayout);
            title(app.AccelerationGraph, 'Acceleration Graph')
            xlabel(app.AccelerationGraph, 'Time')
            ylabel(app.AccelerationGraph, 'Acceleration')
            zlabel(app.AccelerationGraph, 'Z')
            app.SpeedGraph = uiaxes(app.GridLayout);
            title(app.SpeedGraph, 'Speed Graph')
            xlabel(app.SpeedGraph, 'Time')
            ylabel(app.SpeedGraph, 'Speed')
            zlabel(app.SpeedGraph, 'Z')
            app.ToolbarPanel = uipanel(app.GridLayout);
            app.ToolbarPanel.Title = 'Toolbar';
            app.ToolbarPanelGridLayout = uigridlayout(app.ToolbarPanel);
            app.StopButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = '■Stop';
            app.LoadButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ClearButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SaveButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.RunButton = uibutton(app.ToolbarPanelGridLayout, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = '▶Run';
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.InformationPanelGridLayout = uigridlayout(app.InformationPanel);
            app.DriverInfoPanel = uipanel(app.InformationPanelGridLayout);
            app.DriverInfoPanel.Title = 'Driver Info';
            app.DriverInfoGridLayout = uigridlayout(app.DriverInfoPanel);
            app.DriverLabel = uilabel(app.DriverInfoGridLayout);
            app.DriverLabel.Text = 'John Doe';
            app.TeamLabel = uilabel(app.DriverInfoGridLayout);
            app.TeamLabel.Text = 'LiveTelemetry';
            app.DriverNumberLabel = uilabel(app.DriverInfoGridLayout);
            app.DriverNumberLabel.Text = '#67';
            app.SessionInfoPanel = uipanel(app.InformationPanelGridLayout);
            app.SessionInfoPanel.Title = 'Session Info';
            app.SessionInfoGridLayoud = uigridlayout(app.SessionInfoPanel);
            app.SessionLabel = uilabel(app.SessionInfoGridLayoud);
            app.SessionLabel.Text = 'Session';
            app.LapLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapLabel.Text = 'Lap';
            app.LapTimeLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapTimeLabel.Text = 'Lap Time';
            app.GapLabel = uilabel(app.SessionInfoGridLayoud);
            app.GapLabel.Text = 'Gap';
            app.PositionLabel = uilabel(app.SessionInfoGridLayoud);
            app.PositionLabel.Text = 'Position';
            app.SessionTypeLabel = uilabel(app.SessionInfoGridLayoud);
            app.SessionTypeLabel.Text = 'Training';
            app.LapCountLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapCountLabel.Text = '1/67';
            app.LapTimeValueLabel = uilabel(app.SessionInfoGridLayoud);
            app.LapTimeValueLabel.Text = '0:00.000';
            app.GapTimeLabel = uilabel(app.SessionInfoGridLayoud);
            app.GapTimeLabel.Text = '0s';
            app.PositionValueLabel = uilabel(app.SessionInfoGridLayoud);
            app.PositionValueLabel.Text = 'P67';
            app.StatusPanel = uipanel(app.InformationPanelGridLayout);
            app.StatusPanel.Title = 'Status';
            app.SessionInfoGridLayoud_2 = uigridlayout(app.StatusPanel);
            app.TireLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireLabel.Text = 'Tire';
            app.TireLifeLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireLifeLabel.Text = 'Tire Life';
            app.FuelLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.FuelLabel.Text = 'Fuel';
            app.DistanceLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.DistanceLabel.Text = 'Distance';
            app.BestLapLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.BestLapLabel.Text = 'Best Lap';
            app.TireHealthLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireHealthLabel.Text = 'Good';
            app.TireLifeLapsLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.TireLifeLapsLabel.Text = '67 Laps';
            app.FuelPercentageLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.FuelPercentageLabel.Text = '100%';
            app.DistanceTraveledLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.DistanceTraveledLabel.Text = '0mi';
            app.BestLapValueLabel = uilabel(app.SessionInfoGridLayoud_2);
            app.BestLapValueLabel.Text = '0:000.000';
            app.UpdateInformationButton = uibutton(app.InformationPanelGridLayout, 'push');
            app.UpdateInformationButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateInformationButtonPushed, true);
            app.UpdateInformationButton.Text = 'Update Information';
            app.SpeedLiveValuePanel = uipanel(app.GridLayout);
            app.SpeedLiveValuePanel.Title = 'Speed Live Value';
            app.SpeedLiveValueGridLayout = uigridlayout(app.SpeedLiveValuePanel);
            app.SpeedLiveValueLabel = uilabel(app.SpeedLiveValueGridLayout);
            app.SpeedLiveValueLabel.Text = '0';
            app.SpeedUnitsLabel = uilabel(app.SpeedLiveValueGridLayout);
            app.SpeedUnitsLabel.Text = 'mph';
            app.AccelerationLiveValuePanel = uipanel(app.GridLayout);
            app.AccelerationLiveValuePanel.Title = 'Acceleration Live Value';
            app.AccelerationLiveValueGridLayout = uigridlayout(app.AccelerationLiveValuePanel);
            app.AccelerationLiveValueLabel = uilabel(app.AccelerationLiveValueGridLayout);
            app.AccelerationLiveValueLabel.Text = '0';
            app.AccelerationUnitsLabel = uilabel(app.AccelerationLiveValueGridLayout);
            app.AccelerationUnitsLabel.Text = 'mi/hr²';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Time'; 'Speed'; 'Acceleration'};
            app.UITable.RowName = {};
            app.TableHeaderPanel = uipanel(app.GridLayout);
            app.TableHeaderGridLayout = uigridlayout(app.TableHeaderPanel);
            app.TimeHeader = uilabel(app.TableHeaderGridLayout);
            app.TimeHeader.Text = 'Time (s)';
            app.SpeedHeader = uilabel(app.TableHeaderGridLayout);
            app.SpeedHeader.Text = 'Speed (mph)';
            app.AccelerationHeader = uilabel(app.TableHeaderGridLayout);
            app.AccelerationHeader.Text = 'Acceleration (mi/hr²)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ui_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end